classdef EvolutiveSysAnalyze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CurrentRobotFitness             matlab.ui.control.UIAxes
        SensorActivation                matlab.ui.control.UIAxes
        AverageRobotFitness             matlab.ui.control.UIAxes
        LinearVelocity                  matlab.ui.control.UIAxes
        AngularVelocity                 matlab.ui.control.UIAxes
        RotationTime                    matlab.ui.control.UIAxes
        ControlSimulationLabel          matlab.ui.control.Label
        SwitchStart                     matlab.ui.control.Switch
        SimulationStatus                matlab.ui.control.Lamp
        SwitchPause                     matlab.ui.control.Switch
        GenesLabel                      matlab.ui.control.Label
        PopulationfitnessLabel          matlab.ui.control.Label
        SensorAngleLabel                matlab.ui.control.Label
        ipv4Label                       matlab.ui.control.Label
        ipv4EditField                   matlab.ui.control.EditField
        ROSstatus                       matlab.ui.control.Lamp
        ROSLabel                        matlab.ui.control.Label
        SimulationLabel                 matlab.ui.control.Label
        ConnectROSButton                matlab.ui.control.Button
        SensorAngle                     matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        FixedGenesTab                   matlab.ui.container.Tab
        SetFixedGenesLabel              matlab.ui.control.Label
        SensorActivationValue           matlab.ui.control.NumericEditField
        SensorActivationCheckBox        matlab.ui.control.CheckBox
        LinearVelocityCheckBox          matlab.ui.control.CheckBox
        RotationTimeCheckBox            matlab.ui.control.CheckBox
        AngularVelocityCheckBox         matlab.ui.control.CheckBox
        SensorAngleCheckBox             matlab.ui.control.CheckBox
        LinearVelocityValue             matlab.ui.control.NumericEditField
        RotationTimeValue               matlab.ui.control.NumericEditField
        AngularVelocityValue            matlab.ui.control.NumericEditField
        SensorAngleValue                matlab.ui.control.NumericEditField
        mLabel                          matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        dgsLabel                        matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        dgLabel                         matlab.ui.control.Label
        PressureTab                     matlab.ui.container.Tab
        TypesofPressureLabel            matlab.ui.control.Label
        PredationCheckBox               matlab.ui.control.CheckBox
        BackMutationPreventionCheckBox  matlab.ui.control.CheckBox
        PredationValue                  matlab.ui.control.NumericEditField
        genLabel                        matlab.ui.control.Label
        EvolutionTab                    matlab.ui.container.Tab
        MutationLabel                   matlab.ui.control.Label
        MutationCheckBox                matlab.ui.control.CheckBox
        MutationValue                   matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        CrossingCheckBox                matlab.ui.control.CheckBox
        NeutralizationCheckBox          matlab.ui.control.CheckBox
        NeutralizationValue             matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        QtygenerationsinmeanCheckBox    matlab.ui.control.CheckBox
        QtGen                           matlab.ui.control.NumericEditField
        QtybestrobotsCheckBox           matlab.ui.control.CheckBox
        BestRobots                      matlab.ui.control.NumericEditField
        CrossingLabel_2                 matlab.ui.control.Label
        minGenView                      matlab.ui.control.Slider
        AnalyzeEvolutionLabel           matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        ManageDataTab                   matlab.ui.container.Tab
        ExportButton                    matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        ExportGenerationsDataLabel      matlab.ui.control.Label
        ImportGenerationsDataLabel      matlab.ui.control.Label
        SaveGraphsTab                   matlab.ui.container.Tab
        SavealltoPDFfileLabel           matlab.ui.control.Label
        SavesinglegraphLabel            matlab.ui.control.Label
        SelectGraphImage                matlab.ui.control.DropDown
        SaveImage                       matlab.ui.control.Button
        SavePDF                         matlab.ui.control.Button
        returnGeneration                matlab.ui.control.Button
        ReturntothisgenerationLabel     matlab.ui.control.Label
        SimulationParametersLabel       matlab.ui.control.Label
        TreeVisualization               matlab.ui.container.Tree
        GraphvisualizationLabel         matlab.ui.control.Label
        CurrentGenerationSpinnerLabel   matlab.ui.control.Label
        NumberGenView                   matlab.ui.control.Spinner
        DatamanagementLabel             matlab.ui.control.Label
        ManageGenerationLabel           matlab.ui.control.Label
        maxGenView                      matlab.ui.control.Slider
    end

    
    properties (Access = private)
        SensorAnglePolar % Polar graph (Sensor Angle)
        myTimer
        infosub
        % Variables to plotting
        x    = 0;
        yFt  = zeros(7,1);
        yAv  = zeros(7,1);
        ySA  = zeros(7,1);
        yLV  = zeros(7,1);
        yAV  = zeros(7,1);
        yRT  = zeros(7,1);
        ySAn = zeros(7,1);
        evolConfigPub;
        controlSimPub;
        configGenesPub;
        tempFilename; % Temp file directory
        minX =   1;
        maxX =   1;
        minGen = 1;
        maxGen = 0;
        linesToPlot = ones(7,1);
    end
    
    methods (Access = private)


        function [] = updatePlots(~, ~, info,app)
            disp('updatePlots');
            if(1)%robotics.ros.internal.Global.isNodeActive)
                %----- Update Interface -----%
                
                genLimit = [double(app.minGen) max(double(info.Generation),2)];
                app.minGenView.Limits = genLimit;
                app.maxGenView.Limits = genLimit;

                app.NumberGenView.Limits = genLimit;
%                 app.minGenView.MajorTicks = double(app.minGen):max(double(info.Generation),2);
%                 app.maxGen = info.Generation;
                %----- Write on temp file -----%
                info.Generation
                fileID = fopen(app.tempFilename, 'a+' );
                if(info.Generation == (double(app.maxGen)+1))
                    fprintf( fileID, 'gen %d\n',info.Generation);
                    for i=1:6
                        fprintf( fileID, '\trobot%d: %f %f %f %f %f %f %f\n',i, info.Fitness(i), info.AvFitness(i), info.SensorActivation(i), info.LinearVelocity(i), info.AngularVelocity(i), info.RotationTime(i), info.SensorAngle(i));
                    end
                    app.maxGen = info.Generation;
                end
                fclose(fileID);

                %----- Update plot data from temp file -----%
                app.plotGraphs();
            end  
        end
        
        
        function [] = plotGraphs(app)
            if(app.maxGenView.Value >= max(double(app.maxGen),2)-1)
              app.maxGenView.Value = max(double(app.maxGen),2);
              app.maxX = app.maxGenView.Value;
            end
            
             fileID = fopen(app.tempFilename, 'r' );
                short_word = fscanf(fileID, '%s+');
                while(short_word~="-----Data-----")% Ignore start comment
                    short_word = fscanf(fileID, '%s+');
                end
                
                % Setting each variable to empty
                infoDataInitials = {'Ft','Av','SA','LV','AV','RT','SAn'};
                for i=1:7
                    eval([ 'app.y' char(infoDataInitials(i)) ' = [];' ]);
                end
                app.x = [];
                
                currGeneration = '0';
                % Loop through each generation
                for i = 1:7
                   eval([ 'clear app.y' char(infoDataInitials(i)) ]);
                end
                
                while(str2num(currGeneration)~=app.maxGen)
                    short_word = fscanf(fileID, '%s+');
                    while(short_word~="gen")
                        short_word = fscanf(fileID, '%s+');
                    end
                    currGeneration = fscanf(fileID, '%s+');
                    
                    % Get data from each robot in generation
                    formatData = '\trobot%d: %f %f %f %f %f %f %f';
                    sizeData = [8 6];
                    generationData = fscanf(fileID, formatData,sizeData)';
                    
                    app.x = [ app.x currGeneration ];
                    
                    for i=1:7
                        newData = generationData(:,i+1);   
                        newData = [newData; mean(newData)];
                        eval([ 'app.y' char(infoDataInitials(i)) ' = horzcat(app.y' char(infoDataInitials(i)) ', newData);' ])
                    end
                end
                
                fclose(fileID);
                
                %----- Update chromosome graphs -----%
                app.maxX = double(app.maxX);
                app.minX = double(app.minX);
                
                for i = 1:7
                    eval([ 'app.y' char(infoDataInitials(i)) ' = app.y' char(infoDataInitials(i)) '(logical(app.linesToPlot),:);' ]);
                end
                
                if(app.maxX-app.minX >= 1)
                    plotARF = plot(app.AverageRobotFitness,app.yAv(:, app.minX:app.maxX)' );
                    set(plotARF(:),'XData',app.minX:app.maxX);
                    plotCRF = plot(app.CurrentRobotFitness,app.yFt(:, app.minX:app.maxX)');
                    set(plotCRF(:),'XData',app.minX:app.maxX);
                    plotSA = plot(app.SensorActivation,app.ySA(:, app.minX:app.maxX)');
                    set(plotSA(:),'XData',app.minX:app.maxX);
                    plotLV = plot(app.LinearVelocity,app.yLV(:, app.minX:app.maxX)');
                    set(plotLV(:),'XData',app.minX:app.maxX);
                    plotAV = plot(app.AngularVelocity,app.yAV(:, app.minX:app.maxX)');
                    set(plotAV(:),'XData',app.minX:app.maxX);
                    plotRT = plot(app.RotationTime,app.yRT(:, app.minX:app.maxX)');
                    set(plotRT(:),'XData',app.minX:app.maxX);
                    plotSAn = plot(app.SensorAngle,app.ySAn(:, app.minX:app.maxX)');
                    set(plotSAn(:),'XData',app.minX:app.maxX);
                    
                    plotNames = ["plotARF","plotCRF","plotSA","plotLV","plotAV","plotRT","plotSAn"];
                    for name = plotNames
                        % To plot the average red line thicker
                        if(app.linesToPlot(7)==1)
                            eval([ 'set(' char(name) char("(sum(app.linesToPlot)),'Color','r','linew',2);") ])
                        end
                    end
                % TODO Polar Plot
%                 app.ySAn = app.ySAn.*(pi/180);
%                 app.ySAn = [app.ySAn ; -(app.ySAn).*(pi/180)]
%                 plotPolarSAn = plot(app.SensorAnglePolar,app.ySAn');
%                 set(plotPolarSAn(:),'YData',1:info.Generation);
%                 set(plotPolarSAn(:),'XData',app.ySAn');
%                 plotPolarSAn(1)
%                 app.SensorAnglePolar.ThetaZeroLocation = 'top';
%                 set(plotPolarSAn(7),'Color','r','linew',2);
%                 set(plotPolarSAn(14),'Color','r','linew',2);
%                 set(plotPolarSAn(15),'Color','r','linew',2);
                end
        end
    
        function [] = publishEvolutiveSystemConfiguration(app)
            if(1)%robotics.ros.internal.Global.isNodeActive)
                app.evolConfigPub = rospublisher('/evolutive_config','obstacle_avoidance_simulation/EvolutiveSystemConfiguration');
                msg = rosmessage(app.evolConfigPub);
                
                if(app.SensorActivationValue.Enable=="on")
                    msg.SensorActivation =  single(app.SensorActivationValue.Value);
                else
                    msg.SensorActivation =  single(-1);
                end
                if(app.LinearVelocityValue.Enable=="on")
                    msg.LinearVelocity =  single(app.LinearVelocityValue.Value);
                else
                    msg.LinearVelocity =  single(-1);
                end
                if(app.AngularVelocityValue.Enable=="on")
                    msg.AngularVelocity =  single(app.AngularVelocityValue.Value);
                else
                    msg.AngularVelocity =  single(-1);
                end
                if(app.RotationTimeValue.Enable=="on")
                    msg.RotationTime =  single(app.RotationTimeValue.Value);
                else
                    msg.RotationTime =  single(-1);
                end
                if(app.SensorAngleValue.Enable=="on")
                    msg.SensorAngle =  single(app.SensorAngleValue.Value);
                else
                    msg.SensorAngle =  single(-1);
                end
                if(app.PredationValue.Enable=="on")
                    msg.Predation =  int32(app.PredationValue.Value);
                else
                    msg.Predation =  int32(-1);
                end
                if(app.MutationValue.Enable=="on")
                    msg.Mutation =  single(app.MutationValue.Value);
                else
                    msg.Mutation =  single(-1);
                end
                if(app.NeutralizationValue.Enable=="on")
                    msg.Neutralization =  single(app.NeutralizationValue.Value);
                else
                    msg.Neutralization =  single(-1);
                end
                
                msg.BackMutationPrevention = app.BackMutationPreventionCheckBox.Value;
                msg.Crossing = app.CrossingCheckBox.Value;
                
 
                if(app.QtygenerationsinmeanCheckBox.Enable=="on")
                    msg.GenMeanFit = app.QtGen.Value;
                else
                    msg.GenMeanFit = 1;
                end
                if(app.QtybestrobotsCheckBox.Enable=="on")
                    msg.QtBestRobot = app.BestRobots.Value;
                else
                    msg.QtBestRobot = 1;
                end
                
                send(app.evolConfigPub,msg);
            end
        end
        
        function [] = publishControlSimulation(app)
            app.controlSimPub = rospublisher('/control_simulation','obstacle_avoidance_simulation/ControlSimulation');
            msg = rosmessage(app.controlSimPub);
            
            if(app.SwitchStart.Value=="Stop")
                msg.SimulationState = 0;
            else
                if(app.SwitchPause.Value=="Run")
                    msg.SimulationState = 1;
                else
                    msg.SimulationState = 2;
                end
            end
            
            send(app.controlSimPub,msg);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Draw Sensor Angle Polar Plot
            app.SensorAnglePolar = polaraxes(app.UIFigure);
            app.SensorAnglePolar.Units = 'pixels';
            app.SensorAnglePolar.Position = [340 20 200 200];
            app.SensorAnglePolar.ThetaZeroLocation = 'top';
            
            % Check ROS Connection
            if(1)%~1)%robotics.ros.internal.Global.isNodeActive)
                rosinit(app.ipv4EditField.Value);
            end
            
            if(1)%robotics.ros.internal.Global.isNodeActive)
                app.ROSstatus.Color='g';
                app.ConnectROSButton.Text='Disconnect ROS';
                app.publishEvolutiveSystemConfiguration;
                app.infosub = rossubscriber('/simulation_info',{@app.updatePlots,app});
            else
                app.ROSstatus.Color='r';
                app.ConnectROSButton.Text='Connect ROS';
            end
            
            %----- Create temp.txt file -----%
            dir = mfilename('fullpath');
            dir = dir(:,1:(size(dir,2)-20));
            filename = fullfile(dir, 'temp.txt');
            app.tempFilename = filename;
            fileID = fopen( filename, 'wt' );
            fprintf( fileID, '-----Evolutionary System Temporary Data-----\n');
            fprintf( fileID, 'gen <generation>\n');
            fprintf( fileID, '\trobot<robotNumber>: <actualFitness> <averageFitness> <sensorActivation> <linearVelocity> <rotationTime> <angularVelocity> <sensorAngle>\n\n');
            fprintf( fileID, '-----Data-----\n');
            fclose(fileID);
            
            %----- Tree figures -----%
             
             t = app.TreeVisualization;
             
             AvNode = uitreenode(t,'Text','Average','NodeData',[]);
             AvNode.Icon = [dir '/UIfigures/AvLine.png'];
             
             for i = 1:6
                 robotX = uitreenode(t,'Text',['Robot' num2str(i)],'NodeData',[]);
                 robotX.Icon = [dir '/UIfigures/Robot' num2str(i) '.png'];
             end
        end

        % Button pushed function: ConnectROSButton
        function ConnectROSButtonPushed(app, event)
            %robotics.ros.internal.Global.isNodeActive
            
            if(1)%robotics.ros.internal.Global.isNodeActive==0)
                rosinit(app.ipv4EditField.Value);
                if(1)%robotics.ros.internal.Global.isNodeActive==1)
                    app.ROSstatus.Color='g';
                    app.ConnectROSButton.Text='Disconnect ROS';
                    
                    app.infosub = rossubscriber('simulation_info');
                end
            else
                rosshutdown;
                if(1)%robotics.ros.internal.Global.isNodeActive==0)
                    app.ROSstatus.Color='r';
                    app.ConnectROSButton.Text='Connect ROS';
                end
            end
        end

        % Value changed function: SwitchStart
        function SwitchStartValueChanged(app, event)
            value = app.SwitchStart.Value;
            
            app.publishControlSimulation;
            if(value=="Start")
                app.SwitchPause.Enable=1;
                app.SwitchPause.Value="Run";
                app.SimulationStatus.Color='g';
            else
                app.SwitchPause.Enable=0;
                app.SwitchPause.Value="Run";
                app.SimulationStatus.Color='r';
            end
            
        end

        % Value changed function: SwitchPause
        function SwitchPauseValueChanged(app, event)
            value = app.SwitchPause.Value;
            
            app.publishControlSimulation;
            if(value=="Run")
                app.SimulationStatus.Color='g';
            else
                app.SimulationStatus.Color='y';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            rosshutdown;
        end

        % Value changed function: SensorActivationCheckBox
        function SensorActivationCheckBoxValueChanged(app, event)
            value = app.SensorActivationCheckBox.Value;
            
            if(value)
                app.SensorActivationValue.Enable=1;
            else
                app.SensorActivationValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: LinearVelocityCheckBox
        function LinearVelocityCheckBoxValueChanged(app, event)
            value = app.LinearVelocityCheckBox.Value;
            
            if(value)
                app.LinearVelocityValue.Enable=1;
            else
                app.LinearVelocityValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: RotationTimeCheckBox
        function RotationTimeCheckBoxValueChanged(app, event)
            value = app.RotationTimeCheckBox.Value;
            
            if(value)
                app.RotationTimeValue.Enable=1;
            else
                app.RotationTimeValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: AngularVelocityCheckBox
        function AngularVelocityCheckBoxValueChanged(app, event)
            value = app.AngularVelocityCheckBox.Value;
            
            if(value)
                app.AngularVelocityValue.Enable=1;
            else
                app.AngularVelocityValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: SensorAngleCheckBox
        function SensorAngleCheckBoxValueChanged(app, event)
            value = app.SensorAngleCheckBox.Value;
            
            if(value)
                app.SensorAngleValue.Enable=1;
            else
                app.SensorAngleValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: BackMutationPreventionCheckBox
        function BackMutationPreventionCheckBoxValueChanged(app, event)
            %             value = app.BackMutationPreventionCheckBox.Value;
            app.publishEvolutiveSystemConfiguration;
        end

        % Value changed function: PredationCheckBox
        function PredationCheckBoxValueChanged(app, event)
            value = app.PredationCheckBox.Value;
            
            if(value)
                app.PredationValue.Enable=1;
            else
                app.PredationValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: MutationCheckBox
        function MutationCheckBoxValueChanged(app, event)
            value = app.MutationCheckBox.Value;
            
            if(value)
                app.MutationValue.Enable=1;
            else
                app.MutationValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: CrossingCheckBox
        function CrossingCheckBoxValueChanged(app, event)
            %             value = app.CrossingCheckBox.Value;
            app.publishEvolutiveSystemConfiguration;
        end

        % Value changed function: NeutralizationCheckBox
        function NeutralizationCheckBoxValueChanged(app, event)
            value = app.NeutralizationCheckBox.Value;
            
            if(value)
                app.NeutralizationValue.Enable=1;
            else
                app.NeutralizationValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: SensorActivationValue
        function SensorActivationValueValueChanged(app, event)
            %             value = app.SensorActivationValue.Value;
            if(app.SensorActivationValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Value changed function: LinearVelocityValue
        function LinearVelocityValueValueChanged(app, event)
            %             value = app.LinearVelocityValue.Value;
            if(app.LinearVelocityValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Value changed function: RotationTimeValue
        function RotationTimeValueValueChanged(app, event)
            %             value = app.RotationTimeValue.Value;
            if(app.RotationTimeValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Value changed function: AngularVelocityValue
        function AngularVelocityValueValueChanged(app, event)
            %             value = app.AngularVelocityValue.Value;
            if(app.AngularVelocityValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Value changed function: SensorAngleValue
        function SensorAngleValueValueChanged(app, event)
            %             value = app.SensorAngleValue.Value;
            if(app.SensorAngleValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Value changed function: PredationValue
        function PredationValueValueChanged(app, event)
            %             value = app.PredationValue.Value;
            if(app.PredationValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Value changed function: MutationValue
        function MutationValueValueChanged(app, event)
            %             value = app.MutationValue.Value;
            if(app.MutationValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Value changed function: NeutralizationValue
        function NeutralizationValueValueChanged(app, event)
            value = app.NeutralizationValue.Value;
            if(app.NeutralizationValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Button pushed function: SaveImage
        function SaveImageButtonPushed(app, event)
            imageName = inputdlg('Write the image name:');
            
            if(isempty(imageName))
                fig = figure('name',[app.SelectGraphImage.Value '.png']);
            else
                fig = figure('name',[imageName{1} '.png']);
            end

            figure(fig);
            subplot(211);
            
            %select a folder
            pathname = uigetdir(pwd, 'Select the folder where you want to save.');
            if(pathname~=0)
                pathname = [pathname '/'];
            
                switch(app.SelectGraphImage.Value)
                    case 'Current Fitness'
                        plotToSave = plot(app.x,app.yFt);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,app.SelectGraphImage.Value);
                        ylabel(gca,'Fitness');
                        xlabel(gca,'Generation');
                    case 'Average Fitness'
                        plotToSave = plot(app.x,app.yAv);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,app.SelectGraphImage.Value);
                        ylabel(gca,'Fitness');
                        xlabel(gca,'Generation');
                    case 'Sensor Activation'
                        plotToSave = plot(app.x,app.ySA);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,app.SelectGraphImage.Value);
                        ylabel(gca,'Distance (m)');
                        xlabel(gca,'Generation');
                    case 'Linear Velocity'
                        plotToSave = plot(app.x,app.yLV);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,app.SelectGraphImage.Value);
                        ylabel(gca,'L. Velocity (m/s)');
                        xlabel(gca,'Generation');
                    case 'Angular Velocity'
                        plotToSave = plot(app.x,app.yAV);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,app.SelectGraphImage.Value);
                        ylabel(gca,'A. Velocity (degree/s)');
                        xlabel(gca,'Generation');
                    case 'Rotation Time'
                        plotToSave = plot(app.x,app.yRT);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,app.SelectGraphImage.Value);
                        ylabel(gca,'Time (seg)');
                        xlabel(gca,'Generation');
                    case 'Sensor Angle'
                        plotToSave = plot(app.x,app.ySAn);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,app.SelectGraphImage.Value);
                        ylabel(gca,'Angle (degree)');
                        xlabel(gca,'Generation');
                end

                if(isempty(imageName))
                    saveas(fig,[ pathname app.SelectGraphImage.Value '.png']);
                else
                    saveas(fig,[ pathname imageName{1} '.png']);
                end
            end
        end

        % Button pushed function: SavePDF
        function SavePDFButtonPushed(app, event)
            imageName = inputdlg('Write the pdf name:');
            
            if(isempty(imageName))
                fig = figure('name',['allGraphs.pdf']);
            else
                fig = figure('name',[imageName{1} '.pdf']);
            end

            figure(fig);
            
            %select a folder
            pathname = uigetdir(pwd, 'Select the folder where you want to save.');
            if(pathname~=0)
                pathname = [pathname '/'];
            
                        subplot(511);
                        plotToSave = plot(app.x,app.yFt);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,'Current Fitness');
                        ylabel(gca,'Fitness');
                        xlabel(gca,'Generation');
                        
                        subplot(512);
                        plotToSave = plot(app.x,app.yAv);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,'Average Fitness');
                        ylabel(gca,'Fitness');
                        xlabel(gca,'Generation');

                        subplot(525);
                        plotToSave = plot(app.x,app.ySA);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,'Sensor Activation');
                        ylabel(gca,'Distance (m)');
                        xlabel(gca,'Generation');
                        
                        subplot(526);
                        plotToSave = plot(app.x,app.yLV);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,'Linear Velocity');
                        ylabel(gca,'L. Velocity (m/s)');
                        xlabel(gca,'Generation');

                        subplot(527);
                        plotToSave = plot(app.x,app.yAV);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,'Angular Velocity');
                        ylabel(gca,'A. Velocity (degree/s)');
                        xlabel(gca,'Generation');

                        subplot(528);
                        plotToSave = plot(app.x,app.yRT);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,'Rotation Time');
                        ylabel(gca,'Time (seg)');
                        xlabel(gca,'Generation');

                        subplot(529);
                        plotToSave = plot(app.x,app.ySAn);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,'Sensor Angle');
                        ylabel(gca,'Angle (degree)');
                        xlabel(gca,'Generation');
            end

            if(isempty(imageName))
                saveas(fig,[ pathname 'allGraphs.pdf']);
            else
                saveas(fig,[ pathname imageName{1} '.pdf']);
            end
        end

        % Value changed function: NumberGenView
        function NumberGenViewValueChanged(app, event)
            app.NumberGenView.Value = round(app.NumberGenView.Value);
            
            if(app.NumberGenView.Value >= app.maxX)
                app.NumberGenView.Value = round(app.maxX-1);
            end
            app.minX = app.NumberGenView.Value;
            app.minGenView.Value = app.NumberGenView.Value;
            app.plotGraphs();
        end

        % Selection changed function: TreeVisualization
        function TreeVisualizationSelectionChanged(app, event)
            selectedNodes = app.TreeVisualization.SelectedNodes;
            selectedNodes.Text(1:5)
            dir = mfilename('fullpath');
            dir = dir(:,1:(size(dir,2)-20));
            noLinePath = [dir '/UIfigures/NoLine.png'];
            if(selectedNodes.Icon == noLinePath)
                if(selectedNodes.Text(1:5) == "Robot")
                    selectedNodes.Icon = [dir '/UIfigures/Robot' selectedNodes.Text(6) '.png'];
                    app.linesToPlot(double(selectedNodes.Text(6)-'0'),1) = 1;
                else
                    selectedNodes.Icon = [dir '/UIfigures/AvLine.png'];
                    app.linesToPlot(7,1) = 1;
                end
                
            else
                selectedNodes.Icon = [dir '/UIfigures/NoLine.png'];
                if(selectedNodes.Text(1)=='A' )
                    app.linesToPlot(7,1) = 0;
                else
                    app.linesToPlot(double(selectedNodes.Text(6)-'0'),1) = 0;
                end
            end
            app.plotGraphs();
            
        end

        % Value changed function: QtygenerationsinmeanCheckBox
        function QtygenerationsinmeanCheckBoxValueChanged(app, event)
            app.publishEvolutiveSystemConfiguration();
        end

        % Value changed function: QtybestrobotsCheckBox
        function QtybestrobotsCheckBoxValueChanged(app, event)
            app.publishEvolutiveSystemConfiguration();
        end

        % Value changed function: BestRobots
        function BestRobotsValueChanged(app, event)
            app.publishEvolutiveSystemConfiguration();
        end

        % Value changed function: QtGen
        function QtGenValueChanged(app, event)
            app.publishEvolutiveSystemConfiguration();
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            fileName = inputdlg('Type the name of the file you want to export:');
            
            if(isempty(fileName))
                fileName = 'PopulationData.txt';
            else
                fileName = [fileName{1} '.txt'];
            end
            
            dir = mfilename('fullpath');
            dir = dir(:,1:(size(dir,2)-20));
            origin = fullfile(dir, 'temp.txt')
            destination = fullfile(dir, fileName)
            
            copyfile(origin,destination);
            
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
             fileName = inputdlg("Type the name of the file you want to import:");
             
             dir = mfilename('fullpath');
             dir = dir(:,1:(size(dir,2)-20));
             
             if(isempty(fileName))
                fileName = 'importData.txt';
            else
                fileName = [fileName{1} '.txt'];
            end
             
             origin = fullfile(dir, fileName)
             destination  = app.tempFilename

            if(isfile(origin))
                 copyfile(origin,destination);
                 
                 fileID = fopen(app.tempFilename, 'r' );
                 while(~feof(fileID))
                    short_word = fscanf(fileID, '%s+');
                    if(short_word=="gen")
                       aux1 = fscanf(fileID, '%s+');
                       aux1 = double(aux1-'0');
                       
                       aux2 = 0;
                       for i=1:(size(aux1,2))
                           aux2 = aux2 + aux1(1,i)*10^(size(aux1,2)-(i));
                       end
                       app.maxGen = aux2;
                       
                       app.maxX = app.maxGen;
                    end
                 end
                genLimit = [ double(app.minGen) double(app.maxGen) ]
                app.maxGenView.Limits = genLimit;
                app.minGenView.Limits = genLimit;
                app.maxGenView.Value = app.maxGen;
                 
                app.configGenesPub = rospublisher('/config_genes','obstacle_avoidance_simulation/ConfigGenes');
                msg = rosmessage(app.configGenesPub);
                
                frewind(fileID);%return to first line
                
                % Send data from the last generation to the robots
                line = fgetl(fileID);
                lastgen = [ 'gen ' num2str(app.maxGen)]
                while(strcmp(line,lastgen)==0)
                    line = fgetl(fileID);
                end
                
                
                % Get data from each robot in generation
                geneNames = ["RobotNumber","SensorActivation","LinearVelocity","AngularVelocity","RotationTime","SensorAngle"];
                formatData = '\trobot%d: %f %f %f %f %f %f %f';
                sizeData = [8 6];
                generationData = fscanf(fileID, formatData,sizeData)';
                
                for robot=1:6
                    nameCont = 1;
                    for i=[1,4,5,6,7,8]
                        newData = generationData(robot,i);  
%                         [ 'msg.' char(geneNames(nameCont)) '(' num2str(robot) ',' num2str(i) ')' ' = newData;' ]
                        eval([ 'msg.' char(geneNames(nameCont)) '(' num2str(robot) ')' ' = newData;' ]);
                        nameCont = nameCont+1;
                    end
                end
                msg.Generation = app.maxGen;
                send(app.configGenesPub,msg);
                fclose(fileID);
            else
                disp([ origin ' do not exists!'])
            end
             app.plotGraphs();
        end

        % Button pushed function: returnGeneration
        function returnGenerationButtonPushed(app, event)
            genLine=0;
            app.minGen = 1;
            app.maxGen = int32(app.NumberGenView.Value)
            % Get line of the generation in the file
            fileID = fopen(app.tempFilename, 'r' );
            frewind(fileID);%return to first line  
            
            line = fgetl(fileID);
            lastgen = [ 'gen ' num2str(int32(app.maxGen))];
            while(strcmp(line,lastgen)==0)
                genLine=genLine+1;
                line = fgetl(fileID);
            end
            
            % Get data from each robot in this generation
            app.configGenesPub = rospublisher('/config_genes','obstacle_avoidance_simulation/ConfigGenes');
            msg = rosmessage(app.configGenesPub);
            geneNames = ["RobotNumber","SensorActivation","LinearVelocity","AngularVelocity","RotationTime","SensorAngle"];
            formatData = '\trobot%d: %f %f %f %f %f %f %f';
            sizeData = [8 6];
            generationData = fscanf(fileID, formatData,sizeData)';
            
            for robot=1:6
                nameCont = 1;
                for i=[1,4,5,6,7,8]
                    newData = generationData(robot,i);  
                    eval([ 'msg.' char(geneNames(nameCont)) '(' num2str(robot) ')' ' = newData;' ]);
                    nameCont = nameCont+1;
                end
            end
            
            msg.Generation = app.maxGen;
            send(app.configGenesPub,msg);
            
            fclose(fileID);
            
            % Delete necessary lines
            fid = fopen(app.tempFilename, 'r' );
            str = textscan(fid,'%s','Delimiter','\n');
            fclose(fid);
            % Delete everthing after this line
            str2 = str{1}(1:(genLine+7));
            % Save as a text file
            fid2 = fopen(app.tempFilename, 'w');
            fprintf(fid2,'%s\n', str2{:});
            fclose(fid2);
            
            app.minX = 1;
            app.maxX = app.maxGen;
            app.minGenView.Limits = [double(app.minX) double(app.maxX)];
            app.maxGenView.Limits = [double(app.minX) double(app.maxX)];
            
            app.plotGraphs();
        end

        % Value changed function: maxGenView
        function maxGenViewValueChanged(app, event)
            app.maxGenView.Value = ceil(app.maxGenView.Value);
            if(app.maxGenView.Value <= app.minX)
                app.maxGenView.Value = ceil(app.minX+1);
            end
            app.maxX = app.maxGenView.Value;
            app.plotGraphs();
        end

        % Value changed function: minGenView
        function minGenViewValueChanged(app, event)
            app.minGenView.Value = round(app.minGenView.Value);
            
            if(app.minGenView.Value >= app.maxX)
                app.minGenView.Value = round(app.maxX-1);
            end
    
            app.minX = app.minGenView.Value;
            app.NumberGenView.Value = app.minGenView.Value;
            app.plotGraphs();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CurrentRobotFitness = uiaxes(app.UIFigure);
            title(app.CurrentRobotFitness, 'Current Robot Fitness')
            xlabel(app.CurrentRobotFitness, 'Generation')
            ylabel(app.CurrentRobotFitness, 'Fitness')
            app.SensorActivation = uiaxes(app.UIFigure);
            title(app.SensorActivation, 'Sensor Activation')
            xlabel(app.SensorActivation, 'Generation')
            ylabel(app.SensorActivation, 'Distance (m)')
            app.AverageRobotFitness = uiaxes(app.UIFigure);
            title(app.AverageRobotFitness, 'Average Robot Fitness')
            xlabel(app.AverageRobotFitness, 'Generation')
            ylabel(app.AverageRobotFitness, 'Fitness')
            app.LinearVelocity = uiaxes(app.UIFigure);
            title(app.LinearVelocity, 'Linear Velocity')
            xlabel(app.LinearVelocity, 'Generation')
            ylabel(app.LinearVelocity, 'L. Velocity (m/s)')
            app.AngularVelocity = uiaxes(app.UIFigure);
            title(app.AngularVelocity, 'Angular Velocity')
            xlabel(app.AngularVelocity, 'Generation')
            ylabel(app.AngularVelocity, 'A. Velocity (degree/s)')
            app.RotationTime = uiaxes(app.UIFigure);
            title(app.RotationTime, 'Rotation Time')
            xlabel(app.RotationTime, 'Generation')
            ylabel(app.RotationTime, 'Time (seg)')
            app.ControlSimulationLabel = uilabel(app.UIFigure);
            app.ControlSimulationLabel.Text = 'Control Simulation';
            app.SwitchStart = uiswitch(app.UIFigure, 'slider');
            app.SwitchStart.Items = {'Stop', 'Start'};
            app.SwitchStart.ValueChangedFcn = createCallbackFcn(app, @SwitchStartValueChanged, true);
            app.SwitchStart.Value = 'Stop';
            app.SimulationStatus = uilamp(app.UIFigure);
            app.SwitchPause = uiswitch(app.UIFigure, 'slider');
            app.SwitchPause.Items = {'Pause', 'Run'};
            app.SwitchPause.ValueChangedFcn = createCallbackFcn(app, @SwitchPauseValueChanged, true);
            app.SwitchPause.Enable = 'off';
            app.SwitchPause.Value = 'Run';
            app.GenesLabel = uilabel(app.UIFigure);
            app.GenesLabel.Text = 'Genes';
            app.PopulationfitnessLabel = uilabel(app.UIFigure);
            app.PopulationfitnessLabel.Text = 'Population fitness';
            app.SensorAngleLabel = uilabel(app.UIFigure);
            app.SensorAngleLabel.Text = 'Sensor Angle';
            app.ipv4Label = uilabel(app.UIFigure);
            app.ipv4Label.Text = 'ipv4:';
            app.ipv4EditField = uieditfield(app.UIFigure, 'text');
            app.ipv4EditField.Value = '172.26.119.111';
            app.ROSstatus = uilamp(app.UIFigure);
            app.ROSLabel = uilabel(app.UIFigure);
            app.ROSLabel.Text = 'ROS';
            app.SimulationLabel = uilabel(app.UIFigure);
            app.SimulationLabel.Text = 'Simulation';
            app.ConnectROSButton = uibutton(app.UIFigure, 'push');
            app.ConnectROSButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectROSButtonPushed, true);
            app.ConnectROSButton.Text = {'Connect ROS'; ''};
            app.SensorAngle = uiaxes(app.UIFigure);
            title(app.SensorAngle, '')
            xlabel(app.SensorAngle, 'Generation')
            ylabel(app.SensorAngle, 'Angle (degree)')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FixedGenesTab = uitab(app.TabGroup);
            app.FixedGenesTab.Title = 'Fixed Genes';
            app.SetFixedGenesLabel = uilabel(app.FixedGenesTab);
            app.SetFixedGenesLabel.Text = 'Set Fixed Genes';
            app.SensorActivationValue = uieditfield(app.FixedGenesTab, 'numeric');
            app.SensorActivationValue.Limits = [0 Inf];
            app.SensorActivationValue.ValueChangedFcn = createCallbackFcn(app, @SensorActivationValueValueChanged, true);
            app.SensorActivationValue.Enable = 'off';
            app.SensorActivationCheckBox = uicheckbox(app.FixedGenesTab);
            app.SensorActivationCheckBox.ValueChangedFcn = createCallbackFcn(app, @SensorActivationCheckBoxValueChanged, true);
            app.SensorActivationCheckBox.Text = 'Sensor Activation';
            app.LinearVelocityCheckBox = uicheckbox(app.FixedGenesTab);
            app.LinearVelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @LinearVelocityCheckBoxValueChanged, true);
            app.LinearVelocityCheckBox.Text = 'Linear Velocity';
            app.RotationTimeCheckBox = uicheckbox(app.FixedGenesTab);
            app.RotationTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @RotationTimeCheckBoxValueChanged, true);
            app.RotationTimeCheckBox.Text = 'Rotation Time';
            app.AngularVelocityCheckBox = uicheckbox(app.FixedGenesTab);
            app.AngularVelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @AngularVelocityCheckBoxValueChanged, true);
            app.AngularVelocityCheckBox.Text = 'Angular Velocity';
            app.SensorAngleCheckBox = uicheckbox(app.FixedGenesTab);
            app.SensorAngleCheckBox.ValueChangedFcn = createCallbackFcn(app, @SensorAngleCheckBoxValueChanged, true);
            app.SensorAngleCheckBox.Text = 'Sensor Angle';
            app.LinearVelocityValue = uieditfield(app.FixedGenesTab, 'numeric');
            app.LinearVelocityValue.ValueChangedFcn = createCallbackFcn(app, @LinearVelocityValueValueChanged, true);
            app.LinearVelocityValue.Enable = 'off';
            app.RotationTimeValue = uieditfield(app.FixedGenesTab, 'numeric');
            app.RotationTimeValue.Limits = [0 Inf];
            app.RotationTimeValue.ValueChangedFcn = createCallbackFcn(app, @RotationTimeValueValueChanged, true);
            app.RotationTimeValue.Enable = 'off';
            app.AngularVelocityValue = uieditfield(app.FixedGenesTab, 'numeric');
            app.AngularVelocityValue.Limits = [0 Inf];
            app.AngularVelocityValue.ValueChangedFcn = createCallbackFcn(app, @AngularVelocityValueValueChanged, true);
            app.AngularVelocityValue.Enable = 'off';
            app.SensorAngleValue = uieditfield(app.FixedGenesTab, 'numeric');
            app.SensorAngleValue.Limits = [0 90];
            app.SensorAngleValue.ValueChangedFcn = createCallbackFcn(app, @SensorAngleValueValueChanged, true);
            app.SensorAngleValue.Enable = 'off';
            app.mLabel = uilabel(app.FixedGenesTab);
            app.mLabel.Text = 'm';
            app.msLabel = uilabel(app.FixedGenesTab);
            app.msLabel.Text = 'm/s';
            app.dgsLabel = uilabel(app.FixedGenesTab);
            app.dgsLabel.Text = 'dg/s';
            app.sLabel = uilabel(app.FixedGenesTab);
            app.sLabel.Text = 's';
            app.dgLabel = uilabel(app.FixedGenesTab);
            app.dgLabel.Text = 'dg';
            app.PressureTab = uitab(app.TabGroup);
            app.PressureTab.Title = 'Pressure';
            app.TypesofPressureLabel = uilabel(app.PressureTab);
            app.TypesofPressureLabel.Text = 'Types of Pressure';
            app.PredationCheckBox = uicheckbox(app.PressureTab);
            app.PredationCheckBox.ValueChangedFcn = createCallbackFcn(app, @PredationCheckBoxValueChanged, true);
            app.PredationCheckBox.Text = 'Predation';
            app.PredationCheckBox.Value = true;
            app.BackMutationPreventionCheckBox = uicheckbox(app.PressureTab);
            app.BackMutationPreventionCheckBox.ValueChangedFcn = createCallbackFcn(app, @BackMutationPreventionCheckBoxValueChanged, true);
            app.BackMutationPreventionCheckBox.Text = 'Back Mutation Prevention';
            app.BackMutationPreventionCheckBox.Value = true;
            app.PredationValue = uieditfield(app.PressureTab, 'numeric');
            app.PredationValue.Limits = [0 Inf];
            app.PredationValue.ValueChangedFcn = createCallbackFcn(app, @PredationValueValueChanged, true);
            app.PredationValue.Value = 10;
            app.genLabel = uilabel(app.PressureTab);
            app.genLabel.Text = 'gen.';
            app.EvolutionTab = uitab(app.TabGroup);
            app.EvolutionTab.Title = 'Evolution';
            app.MutationLabel = uilabel(app.EvolutionTab);
            app.MutationLabel.Text = 'Mutation';
            app.MutationCheckBox = uicheckbox(app.EvolutionTab);
            app.MutationCheckBox.ValueChangedFcn = createCallbackFcn(app, @MutationCheckBoxValueChanged, true);
            app.MutationCheckBox.Text = 'Mutation';
            app.MutationCheckBox.Value = true;
            app.MutationValue = uieditfield(app.EvolutionTab, 'numeric');
            app.MutationValue.Limits = [0 100];
            app.MutationValue.ValueChangedFcn = createCallbackFcn(app, @MutationValueValueChanged, true);
            app.MutationValue.Value = 10;
            app.Label = uilabel(app.EvolutionTab);
            app.Label.Text = '%';
            app.CrossingCheckBox = uicheckbox(app.EvolutionTab);
            app.CrossingCheckBox.ValueChangedFcn = createCallbackFcn(app, @CrossingCheckBoxValueChanged, true);
            app.CrossingCheckBox.Text = 'Crossing (melhor transa com todos)';
            app.CrossingCheckBox.Value = true;
            app.NeutralizationCheckBox = uicheckbox(app.EvolutionTab);
            app.NeutralizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @NeutralizationCheckBoxValueChanged, true);
            app.NeutralizationCheckBox.Text = 'Neutralization';
            app.NeutralizationCheckBox.Value = true;
            app.NeutralizationValue = uieditfield(app.EvolutionTab, 'numeric');
            app.NeutralizationValue.Limits = [0 100];
            app.NeutralizationValue.ValueChangedFcn = createCallbackFcn(app, @NeutralizationValueValueChanged, true);
            app.NeutralizationValue.Value = 50;
            app.Label_2 = uilabel(app.EvolutionTab);
            app.Label_2.Text = '%';
            app.QtygenerationsinmeanCheckBox = uicheckbox(app.EvolutionTab);
            app.QtygenerationsinmeanCheckBox.ValueChangedFcn = createCallbackFcn(app, @QtygenerationsinmeanCheckBoxValueChanged, true);
            app.QtygenerationsinmeanCheckBox.Text = {'Qty. generations '; 'in mean'};
            app.QtygenerationsinmeanCheckBox.Value = true;
            app.QtGen = uieditfield(app.EvolutionTab, 'numeric');
            app.QtGen.Limits = [0 50];
            app.QtGen.ValueChangedFcn = createCallbackFcn(app, @QtGenValueChanged, true);
            app.QtGen.Value = 7;
            app.QtybestrobotsCheckBox = uicheckbox(app.EvolutionTab);
            app.QtybestrobotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @QtybestrobotsCheckBoxValueChanged, true);
            app.QtybestrobotsCheckBox.Text = 'Qty. best robots';
            app.QtybestrobotsCheckBox.Value = true;
            app.BestRobots = uieditfield(app.EvolutionTab, 'numeric');
            app.BestRobots.Limits = [0 100];
            app.BestRobots.ValueChangedFcn = createCallbackFcn(app, @BestRobotsValueChanged, true);
            app.BestRobots.Value = 3;
            app.CrossingLabel_2 = uilabel(app.EvolutionTab);
            app.CrossingLabel_2.Text = 'Crossing';
            app.minGenView = uislider(app.UIFigure);
            app.minGenView.Limits = [1 2];
            app.minGenView.ValueChangedFcn = createCallbackFcn(app, @minGenViewValueChanged, true);
            app.minGenView.Value = 1;
            app.AnalyzeEvolutionLabel = uilabel(app.UIFigure);
            app.AnalyzeEvolutionLabel.Text = 'Analyze Evolution';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.ManageDataTab = uitab(app.TabGroup2);
            app.ManageDataTab.Title = 'Manage Data';
            app.ExportButton = uibutton(app.ManageDataTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ImportButton = uibutton(app.ManageDataTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ExportGenerationsDataLabel = uilabel(app.ManageDataTab);
            app.ExportGenerationsDataLabel.Text = 'Export Generations Data';
            app.ImportGenerationsDataLabel = uilabel(app.ManageDataTab);
            app.ImportGenerationsDataLabel.Text = 'Import Generations Data';
            app.SaveGraphsTab = uitab(app.TabGroup2);
            app.SaveGraphsTab.Title = 'Save Graphs';
            app.SavealltoPDFfileLabel = uilabel(app.SaveGraphsTab);
            app.SavealltoPDFfileLabel.Text = 'Save all to PDF file';
            app.SavesinglegraphLabel = uilabel(app.SaveGraphsTab);
            app.SavesinglegraphLabel.Text = 'Save single graph';
            app.SelectGraphImage = uidropdown(app.SaveGraphsTab);
            app.SelectGraphImage.Items = {'Current Fitness', 'Average Fitness', 'Sensor Activation', 'Linear Velocity', 'Angular Velocity', 'Rotation Time', 'Sensor Angle'};
            app.SelectGraphImage.Value = 'Current Fitness';
            app.SaveImage = uibutton(app.SaveGraphsTab, 'push');
            app.SaveImage.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImage.Text = 'Save';
            app.SavePDF = uibutton(app.SaveGraphsTab, 'push');
            app.SavePDF.ButtonPushedFcn = createCallbackFcn(app, @SavePDFButtonPushed, true);
            app.SavePDF.Text = 'Save';
            app.returnGeneration = uibutton(app.UIFigure, 'push');
            app.returnGeneration.ButtonPushedFcn = createCallbackFcn(app, @returnGenerationButtonPushed, true);
            app.returnGeneration.Text = 'Yes';
            app.ReturntothisgenerationLabel = uilabel(app.UIFigure);
            app.ReturntothisgenerationLabel.Text = 'Return to this generation?';
            app.SimulationParametersLabel = uilabel(app.UIFigure);
            app.SimulationParametersLabel.Text = 'Simulation Parameters';
            app.TreeVisualization = uitree(app.UIFigure);
            app.TreeVisualization.SelectionChangedFcn = createCallbackFcn(app, @TreeVisualizationSelectionChanged, true);
            app.GraphvisualizationLabel = uilabel(app.UIFigure);
            app.GraphvisualizationLabel.Text = 'Graph visualization';
            app.CurrentGenerationSpinnerLabel = uilabel(app.UIFigure);
            app.CurrentGenerationSpinnerLabel.Text = 'Current Generation';
            app.NumberGenView = uispinner(app.UIFigure);
            app.NumberGenView.Limits = [0 Inf];
            app.NumberGenView.ValueChangedFcn = createCallbackFcn(app, @NumberGenViewValueChanged, true);
            app.DatamanagementLabel = uilabel(app.UIFigure);
            app.DatamanagementLabel.Text = 'Data management';
            app.ManageGenerationLabel = uilabel(app.UIFigure);
            app.ManageGenerationLabel.Text = 'Manage Generation';
            app.maxGenView = uislider(app.UIFigure);
            app.maxGenView.Limits = [1 2];
            app.maxGenView.ValueChangedFcn = createCallbackFcn(app, @maxGenViewValueChanged, true);
            app.maxGenView.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EvolutiveSysAnalyze

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end